classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ColorDetectionUIFigure  matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes4                 matlab.ui.control.UIAxes
        UIAxes5                 matlab.ui.control.UIAxes
        UIAxes6                 matlab.ui.control.UIAxes
        Label                   matlab.ui.control.Label
        BrowseButton            matlab.ui.control.Button
        DetectColorsButton      matlab.ui.control.Button
        CountColorsButton       matlab.ui.control.Button
    end

%متحولات عامة في البرنامج تمثل مسار الصورة الأصلية
% وصور الالوان
properties (Access = public)
     complete_name;
     red;
     orange;
     yellow;
     pink;
     purple;
 
            end


            %عند ضغط زر الاستعراض

    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
  %عند اختيار ملف من نور jpg
            [FileName,PathName] = uigetfile('*.jpg','Select the MATLAB code file');
  %حفظ اسم الملف في متحول
            app.complete_name = fullfile(PathName, FileName);
  %قراءة الصورة
            a=imread(app.complete_name);
  %عرض الصورة
            imshow(a,'Parent',app.UIAxes);
        end

        % Button pushed function: DetectColorsButton
        function DetectColorsButtonPushed(app, event)
 I=imread(app.complete_name);
%فصل الصورة لثلاث ألوان
R = I(:,:,1);
G = I(:,:,2);
B = I(:,:,3);
% انشاء مصفوفات لكل لون على حدى
% من نفس حجم الصورة الأصلية
app.red=I;
app.orange=I;
app.yellow=I;
app.pink=I;
app.purple=I;

[w,h]=size(R);
k=0;
%فحص كل بكسل 
%فحص كل بكسل
for i=1:w
    for j=1:h
        % إن كان ضمن هذا المجال فيكون أحمر
        if  (R(i,j)>130)  &&  (G(i,j)<90)  && (B(i,j)<80)

             
                app.red(i,j,:)=255;
       
        else
     
                app.red(i,j,:)=0;


         end
    end
end

for i=1:w
    for j=1:h
        %مثلاُ لون بنفسجي ضمن هذا المجال
   if (R(i,j)>70)  && (R(i,j)<190)  &&  (G(i,j)<120) &&  (G(i,j)>20) && (B(i,j)<160)&& (B(i,j)>60)

        % نحول البكسل للون الأبيض        
       app.purple(i,j,:)=255;
   else
       % والا يكون أسود
        app.purple(i,j,:)=0;
        

    end
    end
end
    
    for i=1:w
    for j=1:h
        
%إن كان البكسل ضمن هذا المجال فيكون اللون زهري
        if  (R(i,j)>180)  &&  (G(i,j)<210)  &&  (G(i,j)>20)  && (B(i,j)<210) &&  (B(i,j)>80)
                
         app.pink(i,j,:)=255;

    else
         app.pink(i,j,:)=0;
        

        end
    end
    end
    
      for i=1:w
    for j=1:h
        
        if  (R(i,j)>170)  &&  (G(i,j)>170)  && (B(i,j)<140)
                
            app.yellow(i,j,:)=255;


    else
        app.yellow(i,j,:)=0;
        

        end
    end
      end
            for i=1:w
    for j=1:h
        
        if  (210<R(i,j))  && (200>G(i,j))  && (100<G(i,j))  && (B(i,j)<140)
                
           app.orange(i,j,:)=255;


    else
        app.orange(i,j,:)=0;
        

        end
    end
            end
 % عرض المصوفات في الواجهة           
 imshow(app.red,'Parent',app.UIAxes2);
 imshow(app.orange,'Parent',app.UIAxes3);
 imshow(app.yellow,'Parent',app.UIAxes4);
 imshow(app.pink,'Parent',app.UIAxes5);
 imshow(app.purple,'Parent',app.UIAxes6);


        end

        % Button pushed function: CountColorsButton
        function CountColorsButtonPushed(app, event)
r=0;
% استخدام ميزات MSER
% للبحث عن العناصر المتصلة ضمن شرط حجم معين
%650 حتى 2000--
I = rgb2gray(app.red);
[mserRegions, mserConnComp] = detectMSERFeatures(I,'RegionAreaRange',[650 2000],'ThresholdDelta',1);
% عرض نتيجة العد لعناصر اللون الاحمر
title(app.UIAxes2,strcat('Red Tulip ',int2str(mserRegions.Count)));
r=r+mserRegions.Count;


I = rgb2gray(app.orange);
[mserRegions, mserConnComp] = detectMSERFeatures(I,'RegionAreaRange',[650 2000],'ThresholdDelta',1);
%عرض نتيجة العد للزهرات البرتقاليات
title(app.UIAxes3,strcat('Orange Tulip ',int2str(mserRegions.Count)));
r=r+mserRegions.Count;

I = rgb2gray(app.yellow);
[mserRegions, mserConnComp] = detectMSERFeatures(I,'RegionAreaRange',[650 2000],'ThresholdDelta',1);
% عرض نتيجة العد لعناصر اللون الاحمر
title(app.UIAxes4,strcat('Yellow Tulip ',int2str(mserRegions.Count)));
r=r+mserRegions.Count;
%عرض نتيجة العد لعناصر الللون الزهري
I = rgb2gray(app.pink);
[mserRegions, mserConnComp] = detectMSERFeatures(I,'RegionAreaRange',[650 2000],'ThresholdDelta',1);
title(app.UIAxes5,strcat('Pink Tulip ',int2str(mserRegions.Count)));
r=r+mserRegions.Count;
% عرض نتيجة العد لعناصر اللون البنفسجي
I = rgb2gray(app.purple);
[mserRegions, mserConnComp] = detectMSERFeatures(I,'RegionAreaRange',[650 2000],'ThresholdDelta',1);
title(app.UIAxes6,strcat('Purple Tulip ',int2str(mserRegions.Count)));
r=r+mserRegions.Count;

% عرض مجموع الازهار ضمن Label 
app.Label.Text=strcat('The Sum Of Flowers Is   ',int2str(r));
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ColorDetectionUIFigure = uifigure;
            app.ColorDetectionUIFigure.Name = 'Color Detection';
            app.Panel = uipanel(app.ColorDetectionUIFigure);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Original Image')
            app.UIAxes2 = uiaxes(app.Panel);
            title(app.UIAxes2, 'Red Tulip')
            app.UIAxes3 = uiaxes(app.Panel);
            title(app.UIAxes3, 'Orange Tulip')
            app.UIAxes4 = uiaxes(app.Panel);
            title(app.UIAxes4, 'Yellow Tulip')
            app.UIAxes5 = uiaxes(app.Panel);
            title(app.UIAxes5, 'Pink Tulip')
            app.UIAxes6 = uiaxes(app.Panel);
            title(app.UIAxes6, 'Purple Tulip')
            app.Label = uilabel(app.Panel);
            app.Label.Text = '';
            app.BrowseButton = uibutton(app.ColorDetectionUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.DetectColorsButton = uibutton(app.ColorDetectionUIFigure, 'push');
            app.DetectColorsButton.ButtonPushedFcn = createCallbackFcn(app, @DetectColorsButtonPushed, true);
            app.DetectColorsButton.Text = 'Detect Colors';
            app.CountColorsButton = uibutton(app.ColorDetectionUIFigure, 'push');
            app.CountColorsButton.ButtonPushedFcn = createCallbackFcn(app, @CountColorsButtonPushed, true);
            app.CountColorsButton.Text = 'Count Colors';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ColorDetectionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ColorDetectionUIFigure)
        end
    end
end